classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartTeachButton                matlab.ui.control.Button
        StartDemonstrationButton        matlab.ui.control.Button
        HitButton                       matlab.ui.control.Button
        SplitButton                     matlab.ui.control.Button
        DoubleButton                    matlab.ui.control.Button
        StandButton                     matlab.ui.control.Button
        CoordinateLabel                 matlab.ui.control.Label
        QueueofCoordinatesSentTextAreaLabel_4  matlab.ui.control.Label
        QueueofCoordinatesSentTextAreaLabel_3  matlab.ui.control.Label
        SendJointRotationButton_2       matlab.ui.control.Button
        Joint4EditField_2               matlab.ui.control.NumericEditField
        Joint4EditField_2Label          matlab.ui.control.Label
        Joint3EditField_2               matlab.ui.control.NumericEditField
        Joint3EditField_2Label          matlab.ui.control.Label
        Joint2EditField_2               matlab.ui.control.NumericEditField
        Joint2EditField_2Label          matlab.ui.control.Label
        Joint1EditField_2               matlab.ui.control.NumericEditField
        Joint1EditField_2Label          matlab.ui.control.Label
        Joint6EditField                 matlab.ui.control.NumericEditField
        Joint6EditFieldLabel            matlab.ui.control.Label
        Joint5EditField                 matlab.ui.control.NumericEditField
        Joint5EditFieldLabel            matlab.ui.control.Label
        Joint4EditField                 matlab.ui.control.NumericEditField
        Joint4EditFieldLabel            matlab.ui.control.Label
        Joint3EditField                 matlab.ui.control.NumericEditField
        Joint3EditFieldLabel            matlab.ui.control.Label
        Joint2EditField                 matlab.ui.control.NumericEditField
        Joint2EditFieldLabel            matlab.ui.control.Label
        Joint1EditField                 matlab.ui.control.NumericEditField
        Joint1EditFieldLabel            matlab.ui.control.Label
        SendJointRotationButton         matlab.ui.control.Button
        QueueofCoordinatesSentTextAreaLabel_2  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        DeleteQueueCoordinateButton     matlab.ui.control.Button
        QueueofCoordinatesSentTextArea  matlab.ui.control.TextArea
        QueueofCoordinatesSentLabel     matlab.ui.control.Label
        SendCartesianCoordinateButton   matlab.ui.control.Button
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        StartContinueButton             matlab.ui.control.Button
        EmergencyStopButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        coordinateQueue = []; % Array of cartesian coordinates that the user inputs
        moveAuboDeg = zeros(1,6); % Array element for user to move individual joints of Aubo i5
        moveDobotDeg = zeros(1,3); % Array element for user to move individual joints of Dobot Magician
        logFile; % Variable to store the log file created
        simulationMode = ''; % Variable to determine if blackjack or teach functionality is required

        eStopPressed = false; % Variable to determine if the emergency stop has been pressed
        startPressed = false; % Variable to determine if the start/continue button has been pressed
        blackjackAction = 0; % Variable to determine what action of blackjack to undergo
    end
    
    methods (Access = private) 
        function UpdateCoordinateQueueText(app)
            % Updating the text box of the new coordiante within the queue
            numCoordiantes = size(app.coordinateQueue,1); % Getting the amount of cartesian coordiantes inputted
            coordinatesText = ''; % Initialising string to be printed into app text-box

            % Looping through each of the coordinates in the queue and
            % appending to the coordiantesText string
            for i = 1:numCoordiantes
                coordinatesText = [coordinatesText, sprintf('Coordinate %d: X = %.2f, Y = %.2f, Z = %.2f\n', ...
                    i, app.coordinateQueue(i, 1), app.coordinateQueue(i, 2), app.coordinateQueue(i, 3))]; %#ok<AGROW>
            end

            % Updating the textbox to show the new coordinate inputted
            app.QueueofCoordinatesSentTextArea.Value = coordinatesText;            
        end
    end

    methods (Access = public)
        function LoadLogFile(app, L)
            % Loading the log file to the app
            app.logFile = L;
        end
        
        function simulationMode = GetChosenMode(app)
            % Getting the simulation mode that has been set by the user
            simulationMode = app.simulationMode;
        end

        function nextPos = GetNextPos(app)
            % Return the next coordinate in que
            nextPos = app.coordinateQueue(1,:);
        end

        function auboQ = GetAuboQ(app)
            % Return the next Aubo Q angles
            auboQ = app.moveAuboDeg;
        end

        function dobotQ = GetDobotQ(app)
            % Return the next Dobot Q angles
            dobotQ = app.moveDobotDeg;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SendCartesianCoordinateButton
        function SendCartesianCoordinateButtonPushed(app, event)
            % Getting the x,y,z values to create a coordiante point
            newCoordinate = [app.XCoordinateEditField.Value, ...
                app.YCoordinateEditField.Value, app.ZCoordinateEditField.Value];

            % Appending the newCoordinate array to coordinateQueue array
            app.coordinateQueue = [app.coordinateQueue; newCoordinate];

            % Updating the coordinate queue text box
            app.UpdateCoordinateQueueText();
        end

        % Button pushed function: DeleteQueueCoordinateButton
        function DeleteQueueCoordinateButtonPushed(app, event)
            % Getting the array element that the user wishes to delete
            coordinateNum = app.EditField.Value;

            % Checking that the entered number is valid coordinate to delete
            if coordinateNum < 1 || coordinateNum > size(app.coordinateQueue,1)
                app.logFile.mlog = {app.logFile.DEBUG,'DeleteQueueCoordinateButtonPushed', ...
                    'Cannot delete a coordinate system outside of queue range'};
                return
            end
            
            % Delete the element at the specified index
            app.coordinateQueue(coordinateNum, :) = [];

            % Updating the coordinate queue text box
            app.UpdateCoordinateQueueText();
        end

        % Button pushed function: SendJointRotationButton_2
        function SendJointRotationButton_2Pushed(app, event)
            % Getting the joint rotations that the user has specified
            app.moveDobotDeg = [app.Joint1EditField_2.Value, app.Joint2EditField_2.Value, ...
                app.Joint3EditField_2.Value, app.Joint4EditField_2.Value];

            % Sending update message to terminal
            app.logFile.mlog = {app.logFile.DEBUG,'SendJointRotationButton_2Pushed', ...
                    'Joint rotation sent to Dobot Magician'};
        end

        % Button pushed function: SendJointRotationButton
        function SendJointRotationButtonPushed(app, event)
            % Getting the joint rotations that the user has specified
            app.moveAuboDeg = [app.Joint1EditField.Value, app.Joint2EditField.Value, ...
                app.Joint3EditField.Value, app.Joint4EditField.Value, ...
                app.Joint5EditField.Value, app.Joint6EditField.Value];

            % Sending update message to terminal
            app.logFile.mlog = {app.logFile.DEBUG,'SendJointRotationButtonPushed', ...
                    'Joint rotation send to Aubo i5'};
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            % Updating e-stop property to opposite of its prior state
            app.eStopPressed = ~app.eStopPressed;

            % if e-stop has been pressed on, start/continue must turn false
            if app.eStopPressed
                % Updating startPressed property of GUI
                app.startPressed = false;
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop pressed, robot function paused'};
            else
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop turned off, press continue button when ready'};
            end
        end

        % Button pushed function: StartContinueButton
        function StartContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if ~app.eStopPressed
                % If e-stop hasn't been pressed, the start property can
                % be turned true - meaning robot can start operation
                app.startPressed = true;
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Robot function started/continued'};
            else
                % Logging that e-stop is on and therefore robots cannot operate
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Cannot start/continue robot operation if e-stop has been pressed'};
            end
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            % Updating blackjack action property to hit state (i.e. 1)
            app.blackjackAction = 1;
            app.logFile.mlog = {app.logFile.DEBUG,'HitButtonPushed', 'User has asked for a hit'};
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            % Updating blackjack action property to stand state (i.e. 2)
            app.blackjackAction = 2;
            app.logFile.mlog = {app.logFile.DEBUG,'StandButtonPushed', 'User has asked to stand'};
        end

        % Button pushed function: DoubleButton
        function DoubleButtonPushed(app, event)
            % Updating blackjack action property to double state (i.e. 3)
            app.blackjackAction = 3;
            app.logFile.mlog = {app.logFile.DEBUG,'DoubleButtonPushed', 'User has asked to double their bet'};
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
            % Updating blackjack action property to split state (i.e. 4)
            app.blackjackAction = 4;
            app.logFile.mlog = {app.logFile.DEBUG,'SplitButtonPushed', 'User has asked to split their bet'};
        end

        % Button pushed function: StartDemonstrationButton
        function StartDemonstrationButtonPushed(app, event)
            % Updating string to determine what mode gui should be within
            app.simulationMode = 'simulation';
            app.logFile.mlog = {app.logFile.DEBUG,'StartDemonstrationButtonPushed', 'Program has entered simulation mode'};
        end

        % Button pushed function: StartTeachButton
        function StartTeachButtonPushed(app, event)
            % Updating string to determine what mode gui should be within
            app.simulationMode = 'teach';
            app.logFile.mlog = {app.logFile.DEBUG,'StartTeachButtonPushed', 'Program has entered teach mode'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.StartContinueButton = uibutton(app.UIFigure, 'push');
            app.StartContinueButton.ButtonPushedFcn = createCallbackFcn(app, @StartContinueButtonPushed, true);
            app.StartContinueButton.Text = 'Start/Continue';
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendCartesianCoordinateButton = uibutton(app.UIFigure, 'push');
            app.SendCartesianCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @SendCartesianCoordinateButtonPushed, true);
            app.SendCartesianCoordinateButton.Text = 'Send Cartesian Coordinate';
            app.QueueofCoordinatesSentLabel = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentLabel.Text = 'Queue of Coordinates Sent:';
            app.QueueofCoordinatesSentTextArea = uitextarea(app.UIFigure);
            app.DeleteQueueCoordinateButton = uibutton(app.UIFigure, 'push');
            app.DeleteQueueCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteQueueCoordinateButtonPushed, true);
            app.DeleteQueueCoordinateButton.Text = 'Delete Queue Coordinate';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.QueueofCoordinatesSentTextAreaLabel_2 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_2.Text = {'Send End-Effector to '; 'Cartesian Coordinate:'};
            app.SendJointRotationButton = uibutton(app.UIFigure, 'push');
            app.SendJointRotationButton.ButtonPushedFcn = createCallbackFcn(app, @SendJointRotationButtonPushed, true);
            app.SendJointRotationButton.Text = 'Send Joint Rotation';
            app.Joint1EditFieldLabel = uilabel(app.UIFigure);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.UIFigure);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.UIFigure);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.UIFigure);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint5EditFieldLabel = uilabel(app.UIFigure);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint6EditFieldLabel = uilabel(app.UIFigure);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1EditField_2Label = uilabel(app.UIFigure);
            app.Joint1EditField_2Label.Text = 'Joint 1';
            app.Joint1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint2EditField_2Label = uilabel(app.UIFigure);
            app.Joint2EditField_2Label.Text = 'Joint 2';
            app.Joint2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint3EditField_2Label = uilabel(app.UIFigure);
            app.Joint3EditField_2Label.Text = 'Joint 3';
            app.Joint3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint4EditField_2Label = uilabel(app.UIFigure);
            app.Joint4EditField_2Label.Text = 'Joint 4';
            app.Joint4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SendJointRotationButton_2 = uibutton(app.UIFigure, 'push');
            app.SendJointRotationButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendJointRotationButton_2Pushed, true);
            app.SendJointRotationButton_2.Text = 'Send Joint Rotation';
            app.QueueofCoordinatesSentTextAreaLabel_3 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_3.Text = 'Dobot Magician Joint Movement:';
            app.QueueofCoordinatesSentTextAreaLabel_4 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_4.Text = 'Aubo i5 Joint Movement:';
            app.CoordinateLabel = uilabel(app.UIFigure);
            app.CoordinateLabel.Text = 'Coordinate:';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.DoubleButton = uibutton(app.UIFigure, 'push');
            app.DoubleButton.ButtonPushedFcn = createCallbackFcn(app, @DoubleButtonPushed, true);
            app.DoubleButton.Text = 'Double';
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StartDemonstrationButton = uibutton(app.UIFigure, 'push');
            app.StartDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartDemonstrationButtonPushed, true);
            app.StartDemonstrationButton.Text = 'Start Demonstration';
            app.StartTeachButton = uibutton(app.UIFigure, 'push');
            app.StartTeachButton.ButtonPushedFcn = createCallbackFcn(app, @StartTeachButtonPushed, true);
            app.StartTeachButton.Text = 'Start Teach';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end